classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginUIFigure      matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        UserEditField      matlab.ui.control.EditField
        LoginButton        matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        PasswordEditField  matlab.ui.control.EditField
        CancelButton       matlab.ui.control.Button
        BottomEditField    matlab.ui.control.EditField
        Image              matlab.ui.control.Image
        Image2             matlab.ui.control.Image
    end

    
    properties (Access = private)
        UsrID;          % user ID
        PswID;          % password
        AlgoApp;        % algorithm app
        Pointer = 0;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.LoginUIFigure,'center');
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if strcmp(app.UsrID,'LittleSwan') && strcmp(app.PswID,'123456')
                app.CancelButton.Enable = 'off';
                % 不确定对话框展示
                d = uiprogressdlg(app.LoginUIFigure,'Title','登录成功',...
                    'Message','请稍候...','Indeterminate','on');
                % 转移到主算法APP
                app.AlgoApp = Main();
                % 重新设置AlgoApp, 使其组件创建过程中屏幕不可见
                app.AlgoApp.UIFigure.Position = [-1000 -500 640 480];
                % 将AlgoApp移至屏幕中央
                movegui(app.AlgoApp.UIFigure,"center");
                % 关闭对话框
                close(d)
                % 删除LoginApp
                delete(app)
            elseif isempty(app.UsrID)
                msgbox('账号为空！')
            elseif isempty(app.PswID)
                msgbox('密码为空!')
            else
                msgbox('账号或密码不正确!')
            end
            
        end

        % Value changed function: UserEditField
        function UserEditFieldValueChanged(app, event)
            if size(app.UserEditField.Value,2) >= 20
                msgbox('账户长度不能超过20位！')
            else
                app.UsrID = app.UserEditField.Value;
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            selection = questdlg('确定退出系统？','退出','Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
        end

        % Close request function: LoginUIFigure
        function LoginUIFigureCloseRequest(app, event)
            selection = questdlg('确定退出系统？','退出','Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
            
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            % 判断密码长度是否超过16
            if length(event.Value) >= 16
                msgbox('密码长度不能超过16位！')
                app.PasswordEditField.Value = '';
                app.PswID = '';
                return
            end
            % 判断编辑字段是否为空
            if isempty(event.Value)
                app.PasswordEditField.Value = '';
                app.PswID = '';
                return
            end
            % 判断密码是否存在中文字符
            chnStr = regexp(event.Value,'[^\x00-\xff]{1,16}','match');
            if ~isempty(chnStr)
                msgbox('密码不能存在中文字符！')
                app.PasswordEditField.Value = '';
                app.PswID = '';
                return
            end
            % 密码隐藏
            strIndex = find(event.Value ~= '*');
            if isempty(strIndex)
                app.PswID(end) = [];
            else
                app.PswID = [app.PswID(1:strIndex-1),event.Value(strIndex),app.PswID(strIndex:end)];
            end
            app.PasswordEditField.Value = char('*' * sign(event.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginUIFigure = uifigure('Visible', 'off');
            app.LoginUIFigure.Name = 'Log In';
            app.LoginUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoginUIFigureCloseRequest, true);
            app.Panel = uipanel(app.LoginUIFigure);
            app.Panel.Title = '登录界面';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '帐    号：';
            app.UserEditField = uieditfield(app.Panel, 'text');
            app.UserEditField.ValueChangedFcn = createCallbackFcn(app, @UserEditFieldValueChanged, true);
            app.LoginButton = uibutton(app.Panel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = '登录';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '密    码：';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '退出';
            app.BottomEditField = uieditfield(app.Panel, 'text');
            app.BottomEditField.Value = '无锡小天鹅 & 华中科技大学';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'LittleSwan.png';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'HUST.png';
            % Show the figure after all components are created
            app.LoginUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginUIFigure)
        end
    end
end