classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Tree                    matlab.ui.container.Tree
        DataNode                matlab.ui.container.TreeNode
        DataImportNode          matlab.ui.container.TreeNode
        DataCheckNode           matlab.ui.container.TreeNode
        AlgoNode                matlab.ui.container.TreeNode
        ParameterSetNode        matlab.ui.container.TreeNode
        ResultNode              matlab.ui.container.TreeNode
        GanttPlotNode           matlab.ui.container.TreeNode
        DataOutputNode          matlab.ui.container.TreeNode
        Label_10                matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        ImportDataPanel         matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        data1Tab                matlab.ui.container.Tab
        UITable1                matlab.ui.control.Table
        data2Tab                matlab.ui.container.Tab
        UITable2                matlab.ui.control.Table
        DataReadButton          matlab.ui.control.Button
        CheckDataPanel          matlab.ui.container.Panel
        TabGroup2               matlab.ui.container.TabGroup
        data1Tab2               matlab.ui.container.Tab
        UITable3                matlab.ui.control.Table
        data2Tab2               matlab.ui.container.Tab
        UITable4                matlab.ui.control.Table
        ParameterSetPanel       matlab.ui.container.Panel
        AlgorithmButton         matlab.ui.control.Button
        PopSizeLabel            matlab.ui.control.Label
        PopSizeEditField        matlab.ui.control.NumericEditField
        ProbCrLabel             matlab.ui.control.Label
        ProbCrEditField         matlab.ui.control.NumericEditField
        ProbMu1Label            matlab.ui.control.Label
        ProbMu1EditField        matlab.ui.control.NumericEditField
        ProbMu2Label            matlab.ui.control.Label
        ProbMu2EditField        matlab.ui.control.NumericEditField
        ProbMu3Label            matlab.ui.control.Label
        ProbMu3EditField        matlab.ui.control.NumericEditField
        EliteNumLabel           matlab.ui.control.Label
        EliteNumEditField       matlab.ui.control.NumericEditField
        ProblemParaLabel        matlab.ui.control.Label
        ProblemParaEditField    matlab.ui.control.NumericEditField
        DropDownLabel           matlab.ui.control.Label
        ModelSelectionDropDown  matlab.ui.control.DropDown
        IterNumLabel            matlab.ui.control.Label
        IterNumEditField        matlab.ui.control.NumericEditField
        GanttPlotPanel          matlab.ui.container.Panel
        TabGroup3               matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        Tab_2                   matlab.ui.container.Tab
        UIAxes_2                matlab.ui.control.UIAxes
        Tab_3                   matlab.ui.container.Tab
        UIAxes_3                matlab.ui.control.UIAxes
        Tab_4                   matlab.ui.container.Tab
        UIAxes_4                matlab.ui.control.UIAxes
        OutputDataPanel         matlab.ui.container.Panel
        TabGroup4               matlab.ui.container.TabGroup
        Tab_5                   matlab.ui.container.Tab
        UITable5                matlab.ui.control.Table
        Tab_6                   matlab.ui.container.Tab
        UITable6                matlab.ui.control.Table
        Tab_7                   matlab.ui.container.Tab
        UITable7                matlab.ui.control.Table
        DataOutputButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file;                        % 文件名称
        path;                        % 文件路径
        data;                        % 问题数据
        previousValue1 = 100;        % PopSizeEditField前一个值
        previousValue2 = 10;         % EliteNumEditField前一个值
    end
    
    
    methods (Access = private)
        % 将字符类型转为数值类型
        function dataset = strToNum(~,dataset)
            for j = 3:size(dataset,1)
                    %% 对B侧
                    % 更改工位节拍
                    if ~isempty(dataset{j,2})
                        dataset{j,2} = str2double(dataset{j,2});
                    end
                    % 更改MTM工时
                    if ~isempty(dataset{j,5})
                        dataset{j,5} = str2double(dataset{j,5});
                    end
                    %% 对F侧
                    % 更改工位节拍
                    if ~isempty(dataset{j,8})
                        dataset{j,8} = str2double(dataset{j,8});
                    end
                    % 更改MTM工时
                    if ~isempty(dataset{j,11})
                        dataset{j,11} = str2double(dataset{j,11});
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DataReadButton
        function DataReadButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*.xlsx');
            if isequal(app.file,0)
                msgbox('请导入一个Excel文件')
            else
                t1 = readtable(fullfile(app.path, app.file),"Sheet",1,'ReadVariableNames',false);
                app.UITable1.ColumnName = table2cell(t1(1,:));
                app.UITable1.Data = t1(2:end,:);
                app.UITable3.ColumnName = app.UITable1.ColumnName;
                app.UITable3.Data = app.UITable1.Data;
                
                t2 = readtable(fullfile(app.path, app.file),"Sheet",2,'ReadVariableNames',false);
                app.UITable2.ColumnName = table2cell(t2(1,:));
                app.UITable2.Data = t2(2:end,:);
                app.UITable4.ColumnName = app.UITable2.ColumnName;
                app.UITable4.Data = app.UITable2.Data;
            end
            
        end

        % Button pushed function: AlgorithmButton
        function AlgorithmButtonPushed(app, event)

            %% 数据处理
            if isequal(app.file,0)
                msgbox('请导入一个数据表格文件！')
            else
                % 不确定对话框展示
                d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','正在数据处理...','Indeterminate','on');
                [data1,str1] = xlsread(fullfile(app.path, app.file),1);
                [~,str2] = xlsread(fullfile(app.path, app.file),2);
                [Position1,Position2,Position3,Proc_num,Proc_T,taskTightP,taskTightP_all,taskT_0,Tool, ...
                    process_Mu,adjoin_Pro,Line,Pair,taskTightB_all,Xendi,Tendi,Name_Pro] = readData(data1,str1,str2);
                d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','数据处理已完成','Value',1);
            end
            %% 算法参数
            Nind = app.PopSizeEditField.Value;          % 种群大小
            Maxgen = app.IterNumEditField.Value;        % 迭代次数
            Xovr = app.ProbCrEditField.Value;           % 交叉概率
            Mutr_m = app.ProbMu1EditField.Value;        % 染色体变异概率
            Mutr_g = app.ProbMu2EditField.Value;        % 基因变异概率
            Mutr_M = app.ProbMu3EditField.Value;        % 
            Elite = app.EliteNumEditField.Value;        % 精英个数
            takt = app.ProblemParaEditField.Value;         % 节拍时间
            %% 初始化种群
            % 不确定对话框展示
            d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','正在初始化种群...','Indeterminate','on');
            chrom = InitializeDNA(Proc_num,taskTightP_all,taskT_0,Nind,Position1,Position2,takt,Proc_T,Line);
            %% 解码操作
            total_times = zeros(1,Proc_num);
            for k = 1:Proc_num
                a = taskTightB_all{k};
                for u = 1:length(a)
                    b = a(u);
                    total_times(b) = total_times(b) + 1;
                end
            end
            solution_Persons = Decode(Nind,chrom,Proc_num,takt,Proc_T,Position2,Tool, ...
                adjoin_Pro,Pair,taskTightP,taskTightB_all,taskTightP_all,total_times,Position3);
            % 确定对话框展示
            d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','初始化种群已完成','Value',1);
            %% 迭代
            min_P=zeros(1,Maxgen);
            ave_P=zeros(1,Maxgen);
            % 对话框参数
            z = 0;
            dz = 1 / Maxgen;
            d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','开始迭代...','value',z,"Cancelable","on");
            for iter = 1:Maxgen
                % 精英选择
                [chrom_Elite,persons_Elite,chrom_Ordi] = Selection(chrom,solution_Persons,Nind,Elite);
                % 交叉操作
                cro_chrom = Crossover(chrom_Ordi,Nind-Elite,Xovr,Proc_num,taskTightP_all,taskT_0,taskTightB_all);
                %% 变异操作
                mutr_chrom = Mutation(cro_chrom,Nind-Elite,Mutr_m,Mutr_g,Mutr_M,Proc_num, ...
                    taskTightP_all,taskT_0,process_Mu,Line,taskTightB_all);
                % 解码操作
                solution_P = Decode(Nind-Elite,mutr_chrom,Proc_num,takt,Proc_T,Position2,Tool, ...
                    adjoin_Pro,Pair,taskTightP,taskTightB_all,taskTightP_all,total_times,Position3);
                % 合并精英染色体和新染色体
                solution_Persons = [persons_Elite solution_P];
                chrom = [chrom_Elite mutr_chrom];
                % 更新最优解和平均解
                min_P(iter) = min(solution_Persons);
                ave_P(iter) = mean(solution_Persons);
                % 显示对话框
                z = z + dz;
                msg = strcat('当前迭代数/总迭代数:',9,num2str(iter),'/',num2str(Maxgen));
                if d.CancelRequested
                    break;
                end
                d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message',msg,'value',z,"Cancelable","on");
            end
            close(d)
            % 显示对话框
            d = uiprogressdlg(app.UIFigure,'Title','算法运行中','Message','正在绘制算法迭代曲线...','Indeterminate','on');
            % 绘制迭代曲线
            plot(app.UIAxes,1:Maxgen,ave_P,'-',1:Maxgen,min_P,'-.');
            legend(app.UIAxes,{'平均值','最小值'},'Location','Best');
            xlim(app.UIAxes,[1 Maxgen]);
            % 关闭对话框
            close(d);
            % 绘制结果
            Plot(app,min_P,solution_Persons,chrom,Proc_num,takt,Proc_T,Position2,Position3,Tool, ...
                adjoin_Pro,Pair,taskTightP,taskTightB_all,taskTightP_all,total_times,Xendi,Tendi,Name_Pro);
            % 确认对话框
            msg = {'确定','查看结果'};
            confirmFig = uiconfirm(app.UIFigure,'算法运行完成','Finished','Icon','success',...
                'Options',msg,'DefaultOption',1,'CancelOption',2);
            if strcmp(confirmFig,msg(2))
                app.ImportDataPanel.Visible = 'off';
                app.CheckDataPanel.Visible = 'off';
                app.ParameterSetPanel.Visible = 'off';
                app.GanttPlotPanel.Visible = 'on';
                app.OutputDataPanel.Visible = 'off';
            end
        end

        % Value changed function: PopSizeEditField
        function PopSizeEditFieldValueChanged(app, event)
            
            if app.PopSizeEditField.Value < app.EliteNumEditField.Value
                msgbox('请确保种群大小不小于精英个数')
                app.PopSizeEditField.Value = app.previousValue1;
            end
            app.previousValue1 = app.PopSizeEditField.Value;
            
        end

        % Value changed function: EliteNumEditField
        function EliteNumEditFieldValueChanged(app, event)

            if app.EliteNumEditField.Value > app.PopSizeEditField.Value
                msgbox('请确保精英个数不大于种群大小')
                app.EliteNumEditField.Value = app.previousValue2;
            end
            app.previousValue2 = app.EliteNumEditField.Value;
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if strcmp(selectedNodes.Text,'>> 数据读取')
                app.ImportDataPanel.Visible = 'on';
                app.CheckDataPanel.Visible = 'off';
                app.ParameterSetPanel.Visible = 'off';
                app.GanttPlotPanel.Visible = 'off';
                app.OutputDataPanel.Visible = 'off';
            elseif strcmp(selectedNodes.Text,'>> 数据查看')
                app.ImportDataPanel.Visible = 'off';
                app.CheckDataPanel.Visible = 'on';
                app.ParameterSetPanel.Visible = 'off';
                app.GanttPlotPanel.Visible = 'off';
                app.OutputDataPanel.Visible = 'off';
            elseif strcmp(selectedNodes.Text,'>> 参数设置')
                app.ImportDataPanel.Visible = 'off';
                app.CheckDataPanel.Visible = 'off';
                app.ParameterSetPanel.Visible = 'on';
                app.GanttPlotPanel.Visible = 'off';
                app.OutputDataPanel.Visible = 'off';
            elseif strcmp(selectedNodes.Text,'>> 工位图')
                app.ImportDataPanel.Visible = 'off';
                app.CheckDataPanel.Visible = 'off';
                app.ParameterSetPanel.Visible = 'off';
                app.GanttPlotPanel.Visible = 'on';
                app.OutputDataPanel.Visible = 'off';
            elseif strcmp(selectedNodes.Text,'>> 数据导出')
                app.ImportDataPanel.Visible = 'off';
                app.CheckDataPanel.Visible = 'off';
                app.ParameterSetPanel.Visible = 'off';
                app.GanttPlotPanel.Visible = 'off';
                app.OutputDataPanel.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('确定要退出系统吗？','退出','Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
            
        end

        % Size changed function: ImportDataPanel
        function ImportDataPanelSizeChanged(app, event)
            % 调整数据导入面板
            app.ImportDataPanel.Position(1) = 4;
            app.ImportDataPanel.Position(2) = 0;
            app.ImportDataPanel.Position(4) = app.UIFigure.Position(4) - 10;
            % 调整按钮位置，第二列网格底部居中放置
            wid = app.UIFigure.Position(3) - app.GridLayout.ColumnWidth{1};
            wid = wid /2 - app.DataReadButton.Position(3) / 2;
            app.DataReadButton.Position(1) = wid;
            % 调整选项卡组位置，顶部、宽度与另一自调整选项卡组一致
            app.TabGroup.Position(3) = app.ImportDataPanel.Position(3);
            app.TabGroup.Position(4) = app.ImportDataPanel.Position(4) - 60;
            % 调整表格UITable1位置
            app.UITable1.Position(3) = app.TabGroup.Position(3);
            app.UITable1.Position(4) = app.TabGroup.Position(4) - 25;
            % 调整表格UITable2位置
            app.UITable2.Position = app.UITable1.Position;
            
        end

        % Size changed function: CheckDataPanel
        function CheckDataPanelSizeChanged(app, event)
            % 调整数据查看面板
            app.CheckDataPanel.Position(1) = 4;
            app.CheckDataPanel.Position(2) = 0;
            app.CheckDataPanel.Position(4) = app.UIFigure.Position(4) - 10;
            % 调整选项卡组位置，顶部、宽度与另一自调整选项卡组一致
            app.TabGroup2.Position(3) = app.CheckDataPanel.Position(3);
            app.TabGroup2.Position(4) = app.CheckDataPanel.Position(4) - 25;
            % 调整表格UITable3位置
            app.UITable3.Position(3) = app.TabGroup2.Position(3);
            app.UITable3.Position(4) = app.TabGroup2.Position(4) - 25;
            % 调整表格UITable4位置
            app.UITable4.Position = app.UITable3.Position;
            
        end

        % Size changed function: ParameterSetPanel
        function ParameterSetPanelSizeChanged(app, event)
            % 调整参数设置面板
            app.ParameterSetPanel.Position(1) = 4;
            app.ParameterSetPanel.Position(2) = 0;
            app.ParameterSetPanel.Position(4) = app.UIFigure.Position(4) - 10;
            % 右面板宽度
            width = app.UIFigure.Position(3) - app.GridLayout.ColumnWidth{1};
            % 按钮宽度
            wb = app.AlgorithmButton.Position(3);
            % 将窗口纵向分为10份
            height = app.UIFigure.Position(4) / 10;
            % 标签与编辑框间距
            dist = 68;
            % 调整按钮位置
            app.AlgorithmButton.Position(1) = width / 2 - wb / 2;
            app.AlgorithmButton.Position(2) = height;
            % 调整'模型选择'下拉框
            app.DropDownLabel.Position(1) = 0.1 * width;
            app.DropDownLabel.Position(2) = 3 * height;
            app.ModelSelectionDropDown.Position(1) = 0.1 * width + dist;
            app.ModelSelectionDropDown.Position(2) = 3 * height;
            % 调整'精英个数'下拉框
            app.EliteNumLabel.Position(1) = 0.1 * width;
            app.EliteNumLabel.Position(2) = 5 * height;
            app.EliteNumEditField.Position(1) = 0.1 * width + dist;
            app.EliteNumEditField.Position(2) = 5 * height;
            % 调整'种群大小'编辑字段
            app.PopSizeLabel.Position(1) = 0.1 * width;
            app.PopSizeLabel.Position(2) = 6 * height;
            app.PopSizeEditField.Position(1) = 0.1 * width + dist;
            app.PopSizeEditField.Position(2) = 6 * height;
            % 调整'迭代次数'编辑字段
            app.IterNumLabel.Position(1) = 0.1 * width;
            app.IterNumLabel.Position(2) = 7 * height;
            app.IterNumEditField.Position(1) = 0.1 * width + dist;
            app.IterNumEditField.Position(2) = 7 * height;
            % 调整'节拍时间'编辑字段
            app.ProblemParaLabel.Position(1) = 0.6 * width;
            app.ProblemParaLabel.Position(2) = 3 * height;
            app.ProblemParaEditField.Position(1) = 0.6 * width + dist;
            app.ProblemParaEditField.Position(2) = 3 * height;
            % 调整'变异概率3'编辑字段
            app.ProbMu3Label.Position(1) = 0.6 * width;
            app.ProbMu3Label.Position(2) = 4 * height;
            app.ProbMu3EditField.Position(1) = 0.6 * width + dist;
            app.ProbMu3EditField.Position(2) = 4 * height;
            % 调整'变异概率2'编辑字段
            app.ProbMu2Label.Position(1) = 0.6 * width;
            app.ProbMu2Label.Position(2) = 5 * height;
            app.ProbMu2EditField.Position(1) = 0.6 * width + dist;
            app.ProbMu2EditField.Position(2) = 5 * height;
            % 调整'变异概率1'编辑字段
            app.ProbMu1Label.Position(1) = 0.6 * width;
            app.ProbMu1Label.Position(2) = 6 * height;
            app.ProbMu1EditField.Position(1) = 0.6 * width + dist;
            app.ProbMu1EditField.Position(2) = 6 * height;
            % 调整'交叉概率'编辑字段
            app.ProbCrLabel.Position(1) = 0.6 * width;
            app.ProbCrLabel.Position(2) = 7 * height;
            app.ProbCrEditField.Position(1) = 0.6 * width + dist;
            app.ProbCrEditField.Position(2) = 7 * height;
            
        end

        % Size changed function: GanttPlotPanel
        function GanttPlotPanelSizeChanged(app, event)
            % 调整数据查看面板
            app.GanttPlotPanel.Position(1) = 4;
            app.GanttPlotPanel.Position(2) = 0;
            app.GanttPlotPanel.Position(4) = app.UIFigure.Position(4) - 10;
            % 调整选项卡组位置，顶部、宽度与另一自调整选项卡组一致
            app.TabGroup3.Position(3) = app.GanttPlotPanel.Position(3);
            app.TabGroup3.Position(4) = app.GanttPlotPanel.Position(4) - 25;
            % 选项卡组宽度、高度
            width = app.TabGroup3.Position(3);
            height = app.TabGroup3.Position(4);
            % 位置信息
            pos  = [0.10 * width, 0.25 * height, 0.80 * width, 0.50 * height];
            pos2 = [0.05 * width, 0.30 * height, 0.90 * width, 0.40 * height];
            % 调整坐标轴1位置
            app.UIAxes.Position = pos;
            % 调整坐标轴2位置
            app.UIAxes_2.Position = pos2;
            % 调整坐标轴3位置
            app.UIAxes_3.Position = pos2;
            % 调整坐标轴4位置
            app.UIAxes_4.Position = pos2;
        end

        % Size changed function: OutputDataPanel
        function OutputDataPanelSizeChanged(app, event)
            % 调整数据导出面板
            app.OutputDataPanel.Position(1) = 4;
            app.OutputDataPanel.Position(2) = 0;
            app.OutputDataPanel.Position(4) = app.UIFigure.Position(4) - 10;
            % 调整按钮位置，第二列网格底部居中放置
            wid = app.UIFigure.Position(3) - app.GridLayout.ColumnWidth{1};
            wid = wid /2 - app.DataOutputButton.Position(3) / 2;
            app.DataOutputButton.Position(1) = wid;
            % 调整选项卡组位置，顶部、宽度与另一自调整选项卡组一致
            app.TabGroup4.Position(3) = app.OutputDataPanel.Position(3);
            app.TabGroup4.Position(4) = app.OutputDataPanel.Position(4) - 60;
            % 调整表格UITable5位置
            app.UITable5.Position(3) = app.TabGroup4.Position(3);
            app.UITable5.Position(4) = app.TabGroup4.Position(4) - 25;
            % 调整表格UITable6、7位置
            app.UITable6.Position = app.UITable5.Position;
            app.UITable7.Position = app.UITable5.Position;
        end

        % Button pushed function: DataOutputButton
        function DataOutputButtonPushed(app, event)
            [app.file,app.path] = uiputfile('*.xlsx');
            if isequal(app.path,0)
                msgbox('请选择文件所需保存的路径')
            else
                % 如果未输入文件名，则默认文件名为"输出结果"
                if isequal(app.file,0)
                    app.file = '输出结果';
                end
                % 文件绝对路径
                absolutePath = fullfile(app.path, app.file);
                % 前两行为各列名称
                columnName = {'背面','','','','','',...
                              '正面','','','',''; ...
                              '工位序号','工位节拍','工步代号','工步名称','MTM工时','',...
                              '工位号','工位节拍','工步代号','工步名称','MTM工时'};
                % 将列名和实际数据进行合并
                dataset1 = [columnName;table2cell(app.UITable5.Data)];       % 箱体线数据
                dataset2 = [columnName;table2cell(app.UITable6.Data)];       % 桶装线数据
                dataset3 = [columnName;table2cell(app.UITable7.Data)];       % 总装线数据
                % 将时间类数据从字符型转换为数值型
                dataset1 = strToNum(app,dataset1);
                dataset2 = strToNum(app,dataset2);
                dataset3 = strToNum(app,dataset3);
                % 将数据写入到excel文件中
                writecell(dataset1,absolutePath,"Sheet",'箱体线结果');
                writecell(dataset2,absolutePath,"Sheet",'桶装线结果');
                writecell(dataset3,absolutePath,"Sheet",'总装线结果');
                %% 设置Excel格式
                % 创建一个Microsoft EXCEL服务器，并返回句柄
                hExcel = actxserver('excel.application');
                % 设置Excel服务器为不可见状态
                hExcel.Visible = false;
                % 打开文件
                workbook = hExcel.Workbooks.Open(absolutePath);
                % 返回当前工作表句柄
                hSheets = hExcel.ActiveWorkBook.Sheets;
                % 对3个表格进行相同的格式设置
                for k = 1:3
                    % 返回当前表格句柄
                    hSheetk = hSheets.Item(k);
                    % 激活当前表格
                    hSheetk.Activate;
                    % 设置列宽, 工件名称列宽设置大点
                    hSheetk.Range('A1:K500').ColumnWidth = 10;
                    hSheetk.Range('D1:D500').ColumnWidth = 20;
                    hSheetk.Range('J1:J500').ColumnWidth = 20;
                    % 设置单元格对齐方式横向居中和竖直居中
                    hSheetk.Range('A1:K500').HorizontalAlignment = 3;
                    hSheetk.Range('A1:K500').VerticalAlignment = 2;
                    % 设置工步名称为左对齐
                    hSheetk.Range('D3:D500').HorizontalAlignment = 2;
                    hSheetk.Range('J3:J500').HorizontalAlignment = 2;
                    %设置单元格字体格式为加粗
                    hSheetk.Range('A1:K2').Font.bold = 2;
                    % 合并单元格
                    hSheetk.Range('A1:E1').MergeCells = 1;
                    hSheetk.Range('G1:K1').MergeCells = 1;
                end
                % 保存更改且不显示提示框
                hExcel.DisplayAlerts = false;
                workbook.SaveAs(absolutePath);
                % 关闭电子表格
                workbook.Close(false);
                % 关闭服务器
                hExcel.Quit();
                delete(hExcel);
                % 确认框提示数据导出成功
                uiconfirm(app.UIFigure,'数据导出成功','Finished','Icon','success',...
                    'Options',{'确定'},'DefaultOption',1);
            end
        end

        % Value changed function: ModelSelectionDropDown
        function ModelSelectionDropDownValueChanged(app, event)
            if strcmp(app.ModelSelectionDropDown.Value,"I型问题")
                app.ProblemParaLabel.Text = "节拍时间";
                app.ProblemParaEditField.Value = 15;
            elseif strcmp(app.ModelSelectionDropDown.Value,"II型问题")
                app.ProblemParaLabel.Text = "需求人数";
                app.ProblemParaEditField.Value = 75;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {168, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '双边装配线平衡算法求解系统';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = '数据';
            app.DataImportNode = uitreenode(app.DataNode);
            app.DataImportNode.Text = '>> 数据读取';
            app.DataCheckNode = uitreenode(app.DataNode);
            app.DataCheckNode.Text = '>> 数据查看';
            app.AlgoNode = uitreenode(app.Tree);
            app.AlgoNode.Text = '算法';
            app.ParameterSetNode = uitreenode(app.AlgoNode);
            app.ParameterSetNode.Text = '>> 参数设置';
            app.ResultNode = uitreenode(app.Tree);
            app.ResultNode.Text = '结果';
            app.GanttPlotNode = uitreenode(app.ResultNode);
            app.GanttPlotNode.Text = '>> 工位图';
            app.DataOutputNode = uitreenode(app.ResultNode);
            app.DataOutputNode.Text = '>> 数据导出';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '导航菜单';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImportDataPanel = uipanel(app.RightPanel);
            app.ImportDataPanel.Title = '>> 数据读取';
            app.ImportDataPanel.Visible = 'off';
            app.ImportDataPanel.SizeChangedFcn = createCallbackFcn(app, @ImportDataPanelSizeChanged, true);
            app.TabGroup = uitabgroup(app.ImportDataPanel);
            app.data1Tab = uitab(app.TabGroup);
            app.data1Tab.Title = 'data1';
            app.UITable1 = uitable(app.data1Tab);
            app.UITable1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; '...'};
            app.UITable1.RowName = {};
            app.data2Tab = uitab(app.TabGroup);
            app.data2Tab.Title = 'data2';
            app.UITable2 = uitable(app.data2Tab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.DataReadButton = uibutton(app.ImportDataPanel, 'push');
            app.DataReadButton.ButtonPushedFcn = createCallbackFcn(app, @DataReadButtonPushed, true);
            app.DataReadButton.Text = '读取数据';
            app.CheckDataPanel = uipanel(app.RightPanel);
            app.CheckDataPanel.Title = '>> 数据查看';
            app.CheckDataPanel.Visible = 'off';
            app.CheckDataPanel.SizeChangedFcn = createCallbackFcn(app, @CheckDataPanelSizeChanged, true);
            app.TabGroup2 = uitabgroup(app.CheckDataPanel);
            app.data1Tab2 = uitab(app.TabGroup2);
            app.data1Tab2.Title = 'data1';
            app.UITable3 = uitable(app.data1Tab2);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; '...'};
            app.UITable3.RowName = {};
            app.data2Tab2 = uitab(app.TabGroup2);
            app.data2Tab2.Title = 'data2';
            app.UITable4 = uitable(app.data2Tab2);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.ParameterSetPanel = uipanel(app.RightPanel);
            app.ParameterSetPanel.Title = '>> 参数设置';
            app.ParameterSetPanel.SizeChangedFcn = createCallbackFcn(app, @ParameterSetPanelSizeChanged, true);
            app.AlgorithmButton = uibutton(app.ParameterSetPanel, 'push');
            app.AlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmButtonPushed, true);
            app.AlgorithmButton.Text = '运行算法';
            app.PopSizeLabel = uilabel(app.ParameterSetPanel);
            app.PopSizeLabel.Text = '种群大小';
            app.PopSizeEditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.PopSizeEditField.Limits = [1 Inf];
            app.PopSizeEditField.RoundFractionalValues = 'on';
            app.PopSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopSizeEditFieldValueChanged, true);
            app.PopSizeEditField.Value = 100;
            app.ProbCrLabel = uilabel(app.ParameterSetPanel);
            app.ProbCrLabel.Text = '交叉概率';
            app.ProbCrEditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.ProbCrEditField.Limits = [0 1];
            app.ProbCrEditField.Value = 0.9;
            app.ProbMu1Label = uilabel(app.ParameterSetPanel);
            app.ProbMu1Label.Text = '变异概率1';
            app.ProbMu1EditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.ProbMu1EditField.Limits = [0 1];
            app.ProbMu1EditField.Value = 0.3;
            app.ProbMu2Label = uilabel(app.ParameterSetPanel);
            app.ProbMu2Label.Text = '变异概率2';
            app.ProbMu2EditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.ProbMu2EditField.Limits = [0 1];
            app.ProbMu2EditField.Value = 0.5;
            app.ProbMu3Label = uilabel(app.ParameterSetPanel);
            app.ProbMu3Label.Text = '变异概率3';
            app.ProbMu3EditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.ProbMu3EditField.Limits = [0 1];
            app.ProbMu3EditField.Value = 0.7;
            app.EliteNumLabel = uilabel(app.ParameterSetPanel);
            app.EliteNumLabel.Text = '精英个数';
            app.EliteNumEditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.EliteNumEditField.Limits = [0 Inf];
            app.EliteNumEditField.ValueChangedFcn = createCallbackFcn(app, @EliteNumEditFieldValueChanged, true);
            app.EliteNumEditField.Value = 10;
            app.ProblemParaLabel = uilabel(app.ParameterSetPanel);
            app.ProblemParaLabel.Text = '节拍时间';
            app.ProblemParaEditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.ProblemParaEditField.Limits = [0 Inf];
            app.ProblemParaEditField.Value = 15;
            app.DropDownLabel = uilabel(app.ParameterSetPanel);
            app.DropDownLabel.Text = '模型选择';
            app.ModelSelectionDropDown = uidropdown(app.ParameterSetPanel);
            app.ModelSelectionDropDown.Items = {'I型问题', 'II型问题'};
            app.ModelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSelectionDropDownValueChanged, true);
            app.ModelSelectionDropDown.Value = 'I型问题';
            app.IterNumLabel = uilabel(app.ParameterSetPanel);
            app.IterNumLabel.Text = '迭代次数';
            app.IterNumEditField = uieditfield(app.ParameterSetPanel, 'numeric');
            app.IterNumEditField.Limits = [1 Inf];
            app.IterNumEditField.RoundFractionalValues = 'on';
            app.IterNumEditField.Value = 1000;
            app.GanttPlotPanel = uipanel(app.RightPanel);
            app.GanttPlotPanel.Title = '>> 工位图';
            app.GanttPlotPanel.Visible = 'off';
            app.GanttPlotPanel.SizeChangedFcn = createCallbackFcn(app, @GanttPlotPanelSizeChanged, true);
            app.TabGroup3 = uitabgroup(app.GanttPlotPanel);
            app.Tab = uitab(app.TabGroup3);
            app.Tab.Title = '迭代线';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '迭代次数')
            ylabel(app.UIAxes, '适应度值')
            app.Tab_2 = uitab(app.TabGroup3);
            app.Tab_2.Title = '箱体线';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.Tab_3 = uitab(app.TabGroup3);
            app.Tab_3.Title = '桶装线';
            app.UIAxes_3 = uiaxes(app.Tab_3);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.Tab_4 = uitab(app.TabGroup3);
            app.Tab_4.Title = '总装线';
            app.UIAxes_4 = uiaxes(app.Tab_4);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.OutputDataPanel = uipanel(app.RightPanel);
            app.OutputDataPanel.Title = '>> 数据导出';
            app.OutputDataPanel.Visible = 'off';
            app.OutputDataPanel.SizeChangedFcn = createCallbackFcn(app, @OutputDataPanelSizeChanged, true);
            app.TabGroup4 = uitabgroup(app.OutputDataPanel);
            app.Tab_5 = uitab(app.TabGroup4);
            app.Tab_5.Title = '箱体线';
            app.UITable5 = uitable(app.Tab_5);
            app.UITable5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; '...'};
            app.UITable5.RowName = {};
            app.Tab_6 = uitab(app.TabGroup4);
            app.Tab_6.Title = '桶装线';
            app.UITable6 = uitable(app.Tab_6);
            app.UITable6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; '...'};
            app.UITable6.RowName = {};
            app.Tab_7 = uitab(app.TabGroup4);
            app.Tab_7.Title = '总装线';
            app.UITable7 = uitable(app.Tab_7);
            app.UITable7.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; '...'};
            app.UITable7.RowName = {};
            app.DataOutputButton = uibutton(app.OutputDataPanel, 'push');
            app.DataOutputButton.ButtonPushedFcn = createCallbackFcn(app, @DataOutputButtonPushed, true);
            app.DataOutputButton.Text = '导出数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end